classdef APPv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        zmaxS               matlab.ui.control.Spinner
        ZmaxSpinnerLabel    matlab.ui.control.Label
        zminS               matlab.ui.control.Spinner
        ZminLabel           matlab.ui.control.Label
        logoI               matlab.ui.control.Image
        saveB               matlab.ui.control.Button
        scainputS           matlab.ui.control.Spinner
        ScaleFactorLabel    matlab.ui.control.Label
        circumferenceS      matlab.ui.control.NumericEditField
        CircumferenceLabel  matlab.ui.control.Label
        loadB               matlab.ui.control.Button
        scaP                matlab.ui.control.UIAxes
        orP                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TR % Description
        % Property added to store the link object for synced 3D rotation
        LinkObject
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadB
        function loadBPushed(app, event)
            % --- STL Import and Visualization ---
            [file, path] = uigetfile('*.stl', 'Select STL file');
            if isequal(file, 0)
                return;
            end
            fascicle_file = fullfile(path, file);
            app.TR = stlread(fascicle_file);
            
            % Extract P
            P = app.TR.Points;
            
            % Clear the axes before plotting new data
            cla(app.orP);
            cla(app.scaP);
            
            % Plot the STL geometry
            patch(app.orP, ...
                'Faces', app.TR.ConnectivityList, ...
                'Vertices', P, ... 
                'FaceColor', [1 0.1 0.1], ...
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.5);
                
            % 1. DataAspectRatio = [1 1 1] prevents distortion (replaces axis equal)
            % 2. PlotBoxAspectRatio = [1 1 1] forces the physical UI box to be a rigid cube
            % This stops the window from squashing when the object gets wide.
            app.orP.DataAspectRatio = [1 1 1];
            app.orP.PlotBoxAspectRatio = [1 1 1];
            
            app.scaP.DataAspectRatio = [1 1 1];
            app.scaP.PlotBoxAspectRatio = [1 1 1];
            
            camlight(app.orP);
            lighting(app.orP, 'gouraud');
            
            % Link ONLY the cameras (View) so rotating one rotates the other
            app.LinkObject = linkprop([app.orP, app.scaP], {'View'});
            
            % --- Circumference Calculation ---
            % Define slice boundaries (middle 20% of Z)
            Zmin = 1.395; 
            Zmax = 1.4; 
            
            % Extract slice points within the Z-range
            sliceIdx = (P(:, 3) >= Zmin) & (P(:, 3) <= Zmax);
            slicePts = P(sliceIdx, 1:2);
            
            % Check if enough points exist to form a polygon
            if size(slicePts, 1) < 3
                app.circumferenceS.Value = 0;
                return;
            end
            
            % Calculate Convex Hull
            K = convhull(slicePts(:, 1), slicePts(:, 2));
            hullPts = slicePts(K, :);
            
            % Calculate Circumference (sum of Euclidean distances between points)
            d = diff(hullPts, 1, 1);
            currentCirc = sum(sqrt(sum(d.^2, 2)));
            
            % Display result in the UI
            app.circumferenceS.Value = currentCirc;
            
            % --- Overlay Slice Visualization ---
            zLevel = mean([Zmin, Zmax]);
            hold(app.orP, 'on');
            
            % Plot the blue hull outline at the slice level
            plot3(app.orP, hullPts(:, 1), hullPts(:, 2), zLevel * ones(size(hullPts, 1), 1), ...
                'b', 'LineWidth', 2);
            title(app.orP, file, 'Interpreter', 'none');
            hold(app.orP, 'off');
        end

        % Value changed function: scainputS
        function scainputSValueChanged(app, event)
            % Check if STL is loaded
            if isempty(app.TR)
                return;
            end
        
            % Get scale factor from spinner
            scaleFactor = app.scainputS.Value;
        
            % Original points
            P = app.TR.Points;
            F = app.TR.ConnectivityList;
        
            % Compute center
            centerXY = mean(P(:,1:2), 1);
        
            % Apply scaling (XY only)
            P_new = P;
            P_new(:,1) = centerXY(1) + scaleFactor * (P(:,1) - centerXY(1));
            P_new(:,2) = centerXY(2) + scaleFactor * (P(:,2) - centerXY(2));
        
            % Clear the scaled plot
            cla(app.scaP);
            
            %%% === NEW/CHANGED === %%%
            % 1. Plot the ORIGINAL STL as a ghosted red reference
            patch(app.scaP, ...
                'Faces', F, ...
                'Vertices', P, ...
                'FaceColor', [1 0.1 0.1], ... % Same red as the first plot
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.2);            % Very transparent ghost
            %%% =================== %%%
            
            % 2. Plot the SCALED STL over it
            patch(app.scaP, ...
                'Faces', F, ...
                'Vertices', P_new, ...
                'FaceColor', [0.1 0.3 1], ... % Blue
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.6);            % Slightly more solid
            
            % Re-enforce the rigid rules after redrawing
            app.scaP.DataAspectRatio = [1 1 1];
            app.scaP.PlotBoxAspectRatio = [1 1 1];
            
            camlight(app.scaP);
            lighting(app.scaP, 'gouraud');
        end

        % Button pushed function: saveB
        function saveBButtonPushed(app, event)
            % 1. Check if an STL is actually loaded
            if isempty(app.TR)
                uialert(app.UIFigure, 'Please load an STL file first.', 'No Data');
                return;
            end
            
            % 2. Get the current scale factor
            scaleFactor = app.scainputS.Value;
            
            % 3. Recompute the scaled points (exact same logic as your spinner)
            P = app.TR.Points;
            F = app.TR.ConnectivityList;
            centerXY = mean(P(:,1:2), 1);
            
            P_new = P;
            P_new(:,1) = centerXY(1) + scaleFactor * (P(:,1) - centerXY(1));
            P_new(:,2) = centerXY(2) + scaleFactor * (P(:,2) - centerXY(2));
            
            % 4. Create a new triangulation object (required by stlwrite)
            scaledTR = triangulation(F, P_new);
            
            % 5. Create a suggested file name including the scale factor
            % Replace decimal point with an underscore (e.g., 1.5 becomes 1_5)
            scaleStr = strrep(num2str(scaleFactor), '.', '_');
            defaultFileName = sprintf('scaled_factor_%s.stl', scaleStr);
            
            % 6. Open the Save File dialog box
            [file, path] = uiputfile('*.stl', 'Save Scaled STL As', defaultFileName);
            
            % 7. Check if the user clicked 'Cancel'
            if isequal(file, 0)
                return;
            end
            
            % 8. Save the new STL file
            fullFilePath = fullfile(path, file);
            stlwrite(scaledTR, fullFilePath);
            
            % Optional: Show a quick success message
            uialert(app.UIFigure, 'Scaled STL saved successfully!', 'Success');
        end

        % Value changed function: zminS
        function zminSValueChanged(app, event)
            value = app.zminS.Value;
            
        end

        % Value changed function: zmaxS
        function zmaxSValueChanged(app, event)
            value = app.zmaxS.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.orP = uiaxes(app.UIFigure);
            title(app.orP, 'Title')
            xlabel(app.orP, 'X')
            ylabel(app.orP, 'Y')
            zlabel(app.orP, 'Z')
            app.scaP = uiaxes(app.UIFigure);
            title(app.scaP, 'Title')
            xlabel(app.scaP, 'X')
            ylabel(app.scaP, 'Y')
            zlabel(app.scaP, 'Z')
            app.loadB = uibutton(app.UIFigure, 'push');
            app.loadB.ButtonPushedFcn = createCallbackFcn(app, @loadBPushed, true);
            app.loadB.Text = 'Load STL';
            app.CircumferenceLabel = uilabel(app.UIFigure);
            app.CircumferenceLabel.Text = 'Circumference';
            app.circumferenceS = uieditfield(app.UIFigure, 'numeric');
            app.ScaleFactorLabel = uilabel(app.UIFigure);
            app.ScaleFactorLabel.Text = 'Scale Factor';
            app.scainputS = uispinner(app.UIFigure);
            app.scainputS.Step = 0.1;
            app.scainputS.Limits = [0.1 10];
            app.scainputS.ValueChangedFcn = createCallbackFcn(app, @scainputSValueChanged, true);
            app.scainputS.Value = 0.1;
            app.saveB = uibutton(app.UIFigure, 'push');
            app.saveB.ButtonPushedFcn = createCallbackFcn(app, @saveBButtonPushed, true);
            app.saveB.Text = 'Save Scaled Data';
            app.logoI = uiimage(app.UIFigure);
            app.logoI.ImageSource = 'logo.png';
            app.ZminLabel = uilabel(app.UIFigure);
            app.ZminLabel.Text = 'Zmin';
            app.zminS = uispinner(app.UIFigure);
            app.zminS.ValueChangedFcn = createCallbackFcn(app, @zminSValueChanged, true);
            app.ZmaxSpinnerLabel = uilabel(app.UIFigure);
            app.ZmaxSpinnerLabel.Text = 'Zmax';
            app.zmaxS = uispinner(app.UIFigure);
            app.zmaxS.ValueChangedFcn = createCallbackFcn(app, @zmaxSValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end